classdef VentanaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout_Principal            matlab.ui.container.GridLayout
        Container_Principal             matlab.ui.container.Panel
        TabGroup_Principal              matlab.ui.container.TabGroup
        Tab_Inicio                      matlab.ui.container.Tab
        Panel_Inicio                    matlab.ui.container.Panel
        GridLayout_Inicio               matlab.ui.container.GridLayout
        Image_Presentacion              matlab.ui.control.Image
        HTML_Texto_Presentacion         matlab.ui.control.HTML
        Button_Iniciar_Presentacion     matlab.ui.control.Button
        Button_Vermas_Presentacion      matlab.ui.control.Button
        Label_Titulo_Presentacion       matlab.ui.control.Label
        Panel_PiePagina                 matlab.ui.container.Panel
        Button_VerMas                   matlab.ui.control.Button
        Label_TextoInformacion          matlab.ui.control.Label
        Tab_Teoria                      matlab.ui.container.Tab
        Panel_Teoria                    matlab.ui.container.Panel
        GridLayout_Teoria               matlab.ui.container.GridLayout
        Panel_TeoriaIndice              matlab.ui.container.Panel
        Label_TeoriaTitulo_Temario      matlab.ui.control.Label
        Tree_TeoriaTemario              matlab.ui.container.Tree
        Node_SistemaEcuacionesLineales  matlab.ui.container.TreeNode
        Node_MetodosDirectos            matlab.ui.container.TreeNode
        Node_Cramer                     matlab.ui.container.TreeNode
        Node_EliminacindeGauss          matlab.ui.container.TreeNode
        Node_GaussJordan                matlab.ui.container.TreeNode
        Node_DescomposicinLU            matlab.ui.container.TreeNode
        Node_Metodositerativos          matlab.ui.container.TreeNode
        Node_Jacobi                     matlab.ui.container.TreeNode
        Node_GaussSeidel                matlab.ui.container.TreeNode
        Node_GSconrelajacin             matlab.ui.container.TreeNode
        Node_SistemaEcuacionesNoLineales  matlab.ui.container.TreeNode
        Node_MetodoNewtonRaphson        matlab.ui.container.TreeNode
        Panel_TeoriaInteraccion         matlab.ui.container.Panel
        Button_EjemploGrafico           matlab.ui.control.Button
        Label_TeoriaTitulo_Interaccion  matlab.ui.control.Label
        Panel_TeoriaContenido           matlab.ui.container.Panel
        TabGroup_TeoriaContenido        matlab.ui.container.TabGroup
        Tab_MarcoTeorico                matlab.ui.container.Tab
        MarcoteoricoLabel               matlab.ui.control.Label
        Tab_Ejemplo                     matlab.ui.container.Tab
        EjemploLabel                    matlab.ui.control.Label
        Tab_MasInformacion              matlab.ui.container.Tab
        MasinfovLabel                   matlab.ui.control.Label
        Panel_TeoriaContenido_Secciones  matlab.ui.container.Panel
        Button_MarcoTeorico             matlab.ui.control.Button
        Button_Ejemplo                  matlab.ui.control.Button
        Button_MasInformacion           matlab.ui.control.Button
        Tab_Evaluacion                  matlab.ui.container.Tab
        Panel_Evaluacion                matlab.ui.container.Panel
        GridLayout_Evaluacion           matlab.ui.container.GridLayout
        Panel_PanelControl              matlab.ui.container.Panel
        GridLayout_Navegacion           matlab.ui.container.GridLayout
        Label_PanelControlTitulo        matlab.ui.control.Label
        Button_Pregunta01               matlab.ui.control.Button
        Button_Pregunta02               matlab.ui.control.Button
        Button_Pregunta03               matlab.ui.control.Button
        Button_Pregunta04               matlab.ui.control.Button
        Button_Pregunta05               matlab.ui.control.Button
        Button_Pregunta06               matlab.ui.control.Button
        Button_Pregunta07               matlab.ui.control.Button
        Button_Pregunta08               matlab.ui.control.Button
        Button_Pregunta09               matlab.ui.control.Button
        Button_Pregunta10               matlab.ui.control.Button
        Label_TextoNavegacion           matlab.ui.control.Label
        Panel_PanelPregunta             matlab.ui.container.Panel
        TabGroup_Evaluacion             matlab.ui.container.TabGroup
        Tab_Inicial                     matlab.ui.container.Tab
        Panel_InicioEvaluacion          matlab.ui.container.Panel
        Button_IniciarEvaluacion        matlab.ui.control.Button
        Tab_Pregunta01                  matlab.ui.container.Tab
        Tab_Pregunta02                  matlab.ui.container.Tab
        Tab_Pregunta03                  matlab.ui.container.Tab
        Tab_Pregunta04                  matlab.ui.container.Tab
        Tab_Pregunta05                  matlab.ui.container.Tab
        Tab_Pregunta06                  matlab.ui.container.Tab
        Tab_Pregunta07                  matlab.ui.container.Tab
        Tab_Pregunta08                  matlab.ui.container.Tab
        Tab_Pregunta09                  matlab.ui.container.Tab
        Tab_Pregunta10                  matlab.ui.container.Tab
        Tab_Resultado                   matlab.ui.container.Tab
        Panel_PreguntaTitulo            matlab.ui.container.Panel
        Label_TextoTitulo               matlab.ui.control.Label
        Panel_Nota                      matlab.ui.container.Panel
        Label_PanelNota                 matlab.ui.control.Label
        Label_NotaTexto                 matlab.ui.control.Label
        Tab_Calculadora                 matlab.ui.container.Tab
        CalculadoraLabel                matlab.ui.control.Label
        Label_Titulo                    matlab.ui.control.Label
        Label_Tema                      matlab.ui.control.Label
        Panel_Principal                 matlab.ui.container.Panel
        Button_Teoria                   matlab.ui.control.Button
        Button_Evaluacion               matlab.ui.control.Button
        Button_Inicio                   matlab.ui.control.Button
        Button_Calculadora              matlab.ui.control.Button
    end

    
    properties (Access = private)
        %Paltea de colores
        Property_Color01=[0.0588 0.1882 0.3412]; % Azul Oscuro - Letras principal, Fondo alternativo
        Property_Color02=[0 0.3451 0.4784]; % Azul Claro
        Property_Color03=[0 0.5333 0.5686]; % Verde Agua
        Property_Color04=[0.9255 0.9255 0.9255]; % Blanco Frio - Fondo principal, Letras salternativas
        %Variables globales
        Property_BarraNavegacion_BotonSeleccionado=matlab.ui.control.Button;
        Property_TeoriaContenido_BotonSeleccionado=matlab.ui.control.Button;
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Inicializar variables globales
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Inicio;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_MarcoTeorico;
            %Inicializar Temario
            app.Node_SistemaEcuacionesLineales.expand;
            app.Node_MetodosDirectos.expand;
            app.Node_Metodositerativos.expand;
            app.Node_SistemaEcuacionesNoLineales.expand;
        end

        % Button pushed function: Button_Inicio
        function ButtonPushed_Inicio(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Inicio.BackgroundColor = app.Property_Color04;
            app.Button_Inicio.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Inicio;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Inicio;  
        end

        % Button pushed function: Button_Teoria
        function ButtonPushed_Teoria(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Teoria.BackgroundColor = app.Property_Color04;
            app.Button_Teoria.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Teoria;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Teoria;  
        end

        % Button pushed function: Button_Evaluacion
        function ButtonPushed_Evaluacion(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Evaluacion.BackgroundColor = app.Property_Color04;
            app.Button_Evaluacion.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Evaluacion;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Evaluacion;  
        end

        % Button pushed function: Button_Calculadora
        function ButtonPushed_Calculadora(app, event)
            %Modificar color
            app.Property_BarraNavegacion_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Calculadora.BackgroundColor = app.Property_Color04;
            app.Button_Calculadora.FontColor = app.Property_Color03;
            app.Property_BarraNavegacion_BotonSeleccionado=app.Button_Calculadora;
            %Seleccionar pestaña
            app.TabGroup_Principal.SelectedTab=app.Tab_Calculadora;  
        end

        % Button pushed function: Button_MarcoTeorico
        function ButtonPushed_MarcoTeorico(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_MarcoTeorico.BackgroundColor = app.Property_Color04;
            app.Button_MarcoTeorico.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_MarcoTeorico;
            %Seleccionar pestaña
            app.TabGroup_TeoriaContenido.SelectedTab=app.Tab_MarcoTeorico;
        end

        % Button pushed function: Button_Ejemplo
        function ButtonPushed_Ejemplo(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_Ejemplo.BackgroundColor = app.Property_Color04;
            app.Button_Ejemplo.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_Ejemplo;
            %Seleccionar pestaña
            app.TabGroup_TeoriaContenido.SelectedTab=app.Tab_Ejemplo;
        end

        % Button pushed function: Button_MasInformacion
        function ButtonPushed_MasInformacion(app, event)
            %Modificar color
            app.Property_TeoriaContenido_BotonSeleccionado.BackgroundColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado.FontColor = app.Property_Color04;
            app.Button_MasInformacion.BackgroundColor = app.Property_Color04;
            app.Button_MasInformacion.FontColor = app.Property_Color03;
            app.Property_TeoriaContenido_BotonSeleccionado=app.Button_MasInformacion;
            %Seleccionar pestaña
            app.TabGroup_TeoriaContenido.SelectedTab=app.Tab_MasInformacion;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_Principal = uigridlayout(app.UIFigure);
            app.Container_Principal = uipanel(app.GridLayout_Principal);
            app.TabGroup_Principal = uitabgroup(app.Container_Principal);
            app.Tab_Inicio = uitab(app.TabGroup_Principal);
            app.Tab_Inicio.Title = 'Inicio';
            app.Panel_Inicio = uipanel(app.Tab_Inicio);
            app.GridLayout_Inicio = uigridlayout(app.Panel_Inicio);
            app.Image_Presentacion = uiimage(app.GridLayout_Inicio);
            app.Image_Presentacion.ImageSource = 'imagenInicio.png';
            app.HTML_Texto_Presentacion = uihtml(app.GridLayout_Inicio);
            app.HTML_Texto_Presentacion.HTMLSource = '<div style="font-size: 15px;text-align: justify;font-weight:600;"><p style="margin:7%;color:#0f3057;">Bienvenido, esta aplicación tiene como objetivo poder enseñarte el sistema de ecuaciones de manera fácil, interactiva y practica, también puedes realizar operaciones grandes y complejas en nuestra calculadora, te invitamos a empezar con el manual o la teoria.</p></div>';
            app.Button_Iniciar_Presentacion = uibutton(app.GridLayout_Inicio, 'push');
            app.Button_Iniciar_Presentacion.Text = 'Iniciar';
            app.Button_Vermas_Presentacion = uibutton(app.GridLayout_Inicio, 'push');
            app.Button_Vermas_Presentacion.Text = 'Ver más';
            app.Label_Titulo_Presentacion = uilabel(app.GridLayout_Inicio);
            app.Label_Titulo_Presentacion.Text = 'PRESENTACIÓN';
            app.Panel_PiePagina = uipanel(app.Tab_Inicio);
            app.Button_VerMas = uibutton(app.Panel_PiePagina, 'push');
            app.Button_VerMas.Text = 'Quiénes somos';
            app.Label_TextoInformacion = uilabel(app.Panel_PiePagina);
            app.Label_TextoInformacion.Text = 'Información sobre el equipo de desarrollo';
            app.Tab_Teoria = uitab(app.TabGroup_Principal);
            app.Tab_Teoria.Title = 'Teoria';
            app.Panel_Teoria = uipanel(app.Tab_Teoria);
            app.GridLayout_Teoria = uigridlayout(app.Panel_Teoria);
            app.Panel_TeoriaIndice = uipanel(app.GridLayout_Teoria);
            app.Label_TeoriaTitulo_Temario = uilabel(app.Panel_TeoriaIndice);
            app.Label_TeoriaTitulo_Temario.Text = 'Temario';
            app.Tree_TeoriaTemario = uitree(app.Panel_TeoriaIndice);
            app.Node_SistemaEcuacionesLineales = uitreenode(app.Tree_TeoriaTemario);
            app.Node_SistemaEcuacionesLineales.Text = 'Sistema ecuaciones lineales';
            app.Node_MetodosDirectos = uitreenode(app.Node_SistemaEcuacionesLineales);
            app.Node_MetodosDirectos.Text = 'Métodos directos';
            app.Node_Cramer = uitreenode(app.Node_MetodosDirectos);
            app.Node_Cramer.Text = 'Cramer';
            app.Node_EliminacindeGauss = uitreenode(app.Node_MetodosDirectos);
            app.Node_EliminacindeGauss.Text = 'Eliminación de Gauss';
            app.Node_GaussJordan = uitreenode(app.Node_MetodosDirectos);
            app.Node_GaussJordan.Text = 'Gauss-Jordan';
            app.Node_DescomposicinLU = uitreenode(app.Node_MetodosDirectos);
            app.Node_DescomposicinLU.Text = 'Descomposición LU';
            app.Node_Metodositerativos = uitreenode(app.Node_SistemaEcuacionesLineales);
            app.Node_Metodositerativos.Text = 'Métodos iterativos';
            app.Node_Jacobi = uitreenode(app.Node_Metodositerativos);
            app.Node_Jacobi.Text = 'Jacobi';
            app.Node_GaussSeidel = uitreenode(app.Node_Metodositerativos);
            app.Node_GaussSeidel.Text = 'Gauss-Seidel';
            app.Node_GSconrelajacin = uitreenode(app.Node_Metodositerativos);
            app.Node_GSconrelajacin.Text = 'G-S con relajación';
            app.Node_SistemaEcuacionesNoLineales = uitreenode(app.Tree_TeoriaTemario);
            app.Node_SistemaEcuacionesNoLineales.Text = 'Sistema ecuaciones no lineales';
            app.Node_MetodoNewtonRaphson = uitreenode(app.Node_SistemaEcuacionesNoLineales);
            app.Node_MetodoNewtonRaphson.Text = 'Método Newton-Raphson';
            app.Panel_TeoriaInteraccion = uipanel(app.GridLayout_Teoria);
            app.Button_EjemploGrafico = uibutton(app.Panel_TeoriaInteraccion, 'push');
            app.Button_EjemploGrafico.Text = 'Ejemplo grafico';
            app.Label_TeoriaTitulo_Interaccion = uilabel(app.Panel_TeoriaInteraccion);
            app.Label_TeoriaTitulo_Interaccion.Text = 'Interacción';
            app.Panel_TeoriaContenido = uipanel(app.GridLayout_Teoria);
            app.TabGroup_TeoriaContenido = uitabgroup(app.Panel_TeoriaContenido);
            app.Tab_MarcoTeorico = uitab(app.TabGroup_TeoriaContenido);
            app.Tab_MarcoTeorico.Title = 'MarcoTeorico';
            app.MarcoteoricoLabel = uilabel(app.Tab_MarcoTeorico);
            app.MarcoteoricoLabel.Text = 'Marco teorico';
            app.Tab_Ejemplo = uitab(app.TabGroup_TeoriaContenido);
            app.Tab_Ejemplo.Title = 'Ejemplo';
            app.EjemploLabel = uilabel(app.Tab_Ejemplo);
            app.EjemploLabel.Text = 'Ejemplo';
            app.Tab_MasInformacion = uitab(app.TabGroup_TeoriaContenido);
            app.Tab_MasInformacion.Title = 'MasInformacion';
            app.MasinfovLabel = uilabel(app.Tab_MasInformacion);
            app.MasinfovLabel.Text = 'Mas info :v';
            app.Panel_TeoriaContenido_Secciones = uipanel(app.Panel_TeoriaContenido);
            app.Button_MarcoTeorico = uibutton(app.Panel_TeoriaContenido_Secciones, 'push');
            app.Button_MarcoTeorico.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_MarcoTeorico, true);
            app.Button_MarcoTeorico.Text = 'Marco Teórico';
            app.Button_Ejemplo = uibutton(app.Panel_TeoriaContenido_Secciones, 'push');
            app.Button_Ejemplo.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Ejemplo, true);
            app.Button_Ejemplo.Text = 'Ejemplo';
            app.Button_MasInformacion = uibutton(app.Panel_TeoriaContenido_Secciones, 'push');
            app.Button_MasInformacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_MasInformacion, true);
            app.Button_MasInformacion.Text = 'Mas información';
            app.Tab_Evaluacion = uitab(app.TabGroup_Principal);
            app.Tab_Evaluacion.Title = 'Evaluacion';
            app.Panel_Evaluacion = uipanel(app.Tab_Evaluacion);
            app.GridLayout_Evaluacion = uigridlayout(app.Panel_Evaluacion);
            app.Panel_PanelControl = uipanel(app.GridLayout_Evaluacion);
            app.GridLayout_Navegacion = uigridlayout(app.Panel_PanelControl);
            app.Label_PanelControlTitulo = uilabel(app.GridLayout_Navegacion);
            app.Label_PanelControlTitulo.Text = 'Panel de control';
            app.Button_Pregunta01 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta01.Text = {'1'; ''};
            app.Button_Pregunta02 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta02.Text = {'2'; ''};
            app.Button_Pregunta03 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta03.Text = {'3'; ''};
            app.Button_Pregunta04 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta04.Text = {'4'; ''};
            app.Button_Pregunta05 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta05.Text = {'5'; ''};
            app.Button_Pregunta06 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta06.Text = {'6'; ''};
            app.Button_Pregunta07 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta07.Text = {'7'; ''};
            app.Button_Pregunta08 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta08.Text = {'8'; ''};
            app.Button_Pregunta09 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta09.Text = {'9'; ''};
            app.Button_Pregunta10 = uibutton(app.GridLayout_Navegacion, 'push');
            app.Button_Pregunta10.Text = {'10'; ''};
            app.Label_TextoNavegacion = uilabel(app.GridLayout_Navegacion);
            app.Label_TextoNavegacion.Text = {'Navegación'; 'del cuestionario'; ''};
            app.Panel_PanelPregunta = uipanel(app.GridLayout_Evaluacion);
            app.TabGroup_Evaluacion = uitabgroup(app.Panel_PanelPregunta);
            app.Tab_Inicial = uitab(app.TabGroup_Evaluacion);
            app.Tab_Inicial.Title = 'Inicio';
            app.Panel_InicioEvaluacion = uipanel(app.Tab_Inicial);
            app.Button_IniciarEvaluacion = uibutton(app.Panel_InicioEvaluacion, 'push');
            app.Button_IniciarEvaluacion.Text = 'Iniciar Evaluación';
            app.Tab_Pregunta01 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta01.Title = 'Pre1';
            app.Tab_Pregunta02 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta02.Title = 'Pre2';
            app.Tab_Pregunta03 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta03.Title = 'Pre3';
            app.Tab_Pregunta04 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta04.Title = 'Pre4';
            app.Tab_Pregunta05 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta05.Title = 'Pre5';
            app.Tab_Pregunta06 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta06.Title = 'Pre6';
            app.Tab_Pregunta07 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta07.Title = 'Pre7';
            app.Tab_Pregunta08 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta08.Title = 'Pre8';
            app.Tab_Pregunta09 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta09.Title = 'Pre9';
            app.Tab_Pregunta10 = uitab(app.TabGroup_Evaluacion);
            app.Tab_Pregunta10.Title = 'Pre10';
            app.Tab_Resultado = uitab(app.TabGroup_Evaluacion);
            app.Tab_Resultado.Title = 'Resultado';
            app.Panel_PreguntaTitulo = uipanel(app.Panel_PanelPregunta);
            app.Label_TextoTitulo = uilabel(app.Panel_PreguntaTitulo);
            app.Label_TextoTitulo.Text = 'Cuestionario';
            app.Panel_Nota = uipanel(app.GridLayout_Evaluacion);
            app.Label_PanelNota = uilabel(app.Panel_Nota);
            app.Label_PanelNota.Text = '      NOTA :';
            app.Label_NotaTexto = uilabel(app.Panel_Nota);
            app.Label_NotaTexto.Text = '20';
            app.Tab_Calculadora = uitab(app.TabGroup_Principal);
            app.Tab_Calculadora.Title = 'Calculadora';
            app.CalculadoraLabel = uilabel(app.Tab_Calculadora);
            app.CalculadoraLabel.Text = 'Calculadora';
            app.Label_Titulo = uilabel(app.Container_Principal);
            app.Label_Titulo.Text = 'SOFTWARE DE ENSEÑANZA';
            app.Label_Tema = uilabel(app.Container_Principal);
            app.Label_Tema.Text = 'Sistema de ecuaciones';
            app.Panel_Principal = uipanel(app.Container_Principal);
            app.Button_Teoria = uibutton(app.Panel_Principal, 'push');
            app.Button_Teoria.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Teoria, true);
            app.Button_Teoria.Text = 'Teoria';
            app.Button_Evaluacion = uibutton(app.Panel_Principal, 'push');
            app.Button_Evaluacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Evaluacion, true);
            app.Button_Evaluacion.Text = 'Evaluacion';
            app.Button_Inicio = uibutton(app.Panel_Principal, 'push');
            app.Button_Inicio.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Inicio, true);
            app.Button_Inicio.Text = 'Inicio';
            app.Button_Calculadora = uibutton(app.Panel_Principal, 'push');
            app.Button_Calculadora.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Calculadora, true);
            app.Button_Calculadora.Text = 'Calculadora';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end